classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Switch_2                    matlab.ui.control.ToggleSwitch
        Switch_2Label               matlab.ui.control.Label
        Switch                      matlab.ui.control.ToggleSwitch
        SwitchLabel                 matlab.ui.control.Label
        Products_BlueGauge          matlab.ui.control.Gauge
        Products_BlueGaugeLabel     matlab.ui.control.Label
        Products_RedGauge           matlab.ui.control.Gauge
        Products_RedGaugeLabel      matlab.ui.control.Label
        Conveyor_SpeedGauge_2       matlab.ui.control.LinearGauge
        Conveyor_SpeedGauge_2Label  matlab.ui.control.Label
        Gripper_ONLamp              matlab.ui.control.Lamp
        Gripper_ONLampLabel         matlab.ui.control.Label
        Robot_OnLamp                matlab.ui.control.Lamp
        Robot_OnLampLabel           matlab.ui.control.Label
        Robot_SpeedGauge            matlab.ui.control.LinearGauge
        Robot_SpeedGaugeLabel       matlab.ui.control.Label
        Feeding_OnLamp              matlab.ui.control.Lamp
        Feeding_OnLampLabel         matlab.ui.control.Label
        Conveyor_OnLamp             matlab.ui.control.Lamp
        Conveyor_OnLampLabel        matlab.ui.control.Label
    end

    
       properties (Access = private)
        R_G_p    = 0;
        R_X_P    = 0;   % note capital P
        R_X_S    = 0;
        force_Red  = 0;
        force_Blue = 0;
        feederTurn    = 0;
        conveyorSpeed = 0;
        updateTimer   % no initial value
        simout
       end

methods (Access = private)
 function updateApp(app)
            % Safely get values from workspace
            try
                feeder_signal = evalin('base', 'feeder_signal');
                belt_speed = evalin('base', 'belt_speed');
                R_G_pos = evalin('base', 'R_G_pos');
                R_X_Pos = evalin('base', 'R_X_Pos');
                R_X_Spd = evalin('base', 'R_X_Spd');
                force_red = evalin('base', 'force_red');
                force_blue = evalin('base', 'force_blue');
            catch
                feeder_signal = 0;
                belt_speed = 0;
                R_G_pos = 0;
                R_X_Pos = 0;
                R_X_Spd = 0;
                force_red = 0;
                force_blue = 0;
            end

            % Extract last values if arrays
            vars = {'feeder_signal','belt_speed','R_G_pos','R_X_Pos','R_X_Spd','force_red','force_blue'};
            for v = vars
                val = eval(v{1});
                if numel(val) > 1
                    assignin('caller', v{1}, val(end));
                end
            end

            % Update stored app properties
            app.feederTurn = feeder_signal;
            app.conveyorSpeed = belt_speed;
            app.R_G_p = R_G_pos;
            app.R_X_P = R_X_Pos;
            app.R_X_S = R_X_Spd;
            app.force_Red = force_red;
            app.force_Blue = force_blue;

            % Update Feeder lamp
            if app.feederTurn < 0
                app.Feeding_OnLamp.Color = [0 1 0]; % Green
            else
                app.Feeding_OnLamp.Color = [0.5 0.5 0.5];
            end

            % Update Conveyor lamp & gauge
            if app.conveyorSpeed > 0
                app.Conveyor_OnLamp.Color = [1 1 0]; % Yellow
            else
                app.Conveyor_OnLamp.Color = [0.5 0.5 0.5];
            end
            app.Conveyor_SpeedGauge_2.Value = app.conveyorSpeed;

            % Robot ON lamp
              app.Conveyor_SpeedGauge_2.Value = app.conveyorSpeed * 100;

    % --- Robot ON lamp ---
    if app.R_X_P ~= 0
        app.Robot_OnLamp.Color = [0 1 0]; % Green
    else
        app.Robot_OnLamp.Color = [0.5 0.5 0.5];
    end

    % --- Gripper ON lamp ---
    if app.R_G_p < -1.5
        app.Gripper_ONLamp.Color = [0 1 0]; % Green
    else
        app.Gripper_ONLamp.Color = [0.5 0.5 0.5];
    end

    % --- Robot speed gauge (×100) ---
    app.Robot_SpeedGauge.Value = max(abs(app.R_X_S * 100), 0);

            % Product gauges
            threshold = 0;
            redVal = app.force_Red;
            blueVal = app.force_Blue;
            if numel(redVal) > 1, redVal = redVal(end); end
            if numel(blueVal) > 1, blueVal = blueVal(end); end
            app.Products_RedGauge.Value = double(redVal > threshold);
            app.Products_BlueGauge.Value = double(blueVal > threshold);
        end
    end

    methods (Access = public)
        function closeApp(app)
            % وقف التايمر بأمان
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
            stop(app.updateTimer);
            delete(app.updateTimer);
            end
            delete(app.UIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   % Initialize and start timer
            app.updateTimer = timer('ExecutionMode', 'fixedRate', ...
                                    'Period', 0.2, ...
                                    'TimerFcn', @(~,~) app.updateApp());
            start(app.updateTimer);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
           state = app.Switch.Value; % 'On' or 'Off'

    if strcmp(state, 'On')
        app.Conveyor_SpeedGauge_2.Enable = 'on';   % Show normally
    else
        app.Conveyor_SpeedGauge_2.Enable = 'off';  % Grey & shadowed
    end

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            state = app.Switch_2.Value; % 'On' or 'Off'

    if strcmp(state, 'On')
        app.Robot_SpeedGauge.Enable = 'on';
    else
        app.Robot_SpeedGauge.Enable = 'off';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Conveyor_OnLampLabel = uilabel(app.UIFigure);
            app.Conveyor_OnLampLabel.Text = 'Conveyor_On';
            app.Conveyor_OnLamp = uilamp(app.UIFigure);
            app.Feeding_OnLampLabel = uilabel(app.UIFigure);
            app.Feeding_OnLampLabel.Text = 'Feeding_On';
            app.Feeding_OnLamp = uilamp(app.UIFigure);
            app.Robot_SpeedGaugeLabel = uilabel(app.UIFigure);
            app.Robot_SpeedGaugeLabel.Text = 'Robot_Speed';
            app.Robot_SpeedGauge = uigauge(app.UIFigure, 'linear');
            app.Robot_OnLampLabel = uilabel(app.UIFigure);
            app.Robot_OnLampLabel.Text = 'Robot_On';
            app.Robot_OnLamp = uilamp(app.UIFigure);
            app.Gripper_ONLampLabel = uilabel(app.UIFigure);
            app.Gripper_ONLampLabel.Text = 'Gripper_ON';
            app.Gripper_ONLamp = uilamp(app.UIFigure);
            app.Conveyor_SpeedGauge_2Label = uilabel(app.UIFigure);
            app.Conveyor_SpeedGauge_2Label.Text = 'Conveyor_Speed';
            app.Conveyor_SpeedGauge_2 = uigauge(app.UIFigure, 'linear');
            app.Products_RedGaugeLabel = uilabel(app.UIFigure);
            app.Products_RedGaugeLabel.Text = 'Products_Red';
            app.Products_RedGauge = uigauge(app.UIFigure, 'circular');
            app.Products_RedGauge.Limits = [0 4];
            app.Products_RedGauge.MajorTicks = [0 1 2 3 4];
            app.Products_RedGauge.MajorTickLabels = {'0', '1', '2', '3', '4'};
            app.Products_BlueGaugeLabel = uilabel(app.UIFigure);
            app.Products_BlueGaugeLabel.Text = 'Products_Blue';
            app.Products_BlueGauge = uigauge(app.UIFigure, 'circular');
            app.Products_BlueGauge.Limits = [0 4];
            app.Products_BlueGauge.MajorTicks = [0 1 2 3 4];
            app.Products_BlueGauge.MajorTickLabels = {'0', '1', '2', '3', '4'};
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = 'Switch';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end